classdef newDataset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        NewDatasetLabel       matlab.ui.control.Label
        ClearButton           matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        InstructionsforcreatingnewdatasetLabel  matlab.ui.control.Label
        CaptureGestureButton  matlab.ui.control.Button
        NextGestureButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        cam % Description
        displayWindow
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cam = webcam;
            
            vid = videoinput('winvideo',1);
            app.displayWindow = image(app.UIAxes,zeros(720,1280,3));
           
            
            app.UIAxes.XLim = [0,1280];
            app.UIAxes.YLim = [0,720];

            app.UIAxes.Title.String = "Gesture Capture";
            pbaspect(app.UIAxes,[1280,720,1]);
            % Preview the image
            preview(vid,app.displayWindow);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
        
        end

        % Button pushed function: CaptureGestureButton
        function CaptureGestureButtonPushed(app, event)
            pause(1);
            capImage = snapshot(app.cam);
            closepreview;
            app.UIAxes.Title.String = "Captured";
            imagesc(app.UIAxes,capImage);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NewDatasetLabel = uilabel(app.UIFigure);
            app.NewDatasetLabel.Text = 'New Dataset';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.InstructionsforcreatingnewdatasetLabel = uilabel(app.UIFigure);
            app.InstructionsforcreatingnewdatasetLabel.Text = 'Instructions for creating new dataset';
            app.CaptureGestureButton = uibutton(app.UIFigure, 'push');
            app.CaptureGestureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureGestureButtonPushed, true);
            app.CaptureGestureButton.Text = 'Capture Gesture';
            app.NextGestureButton = uibutton(app.UIFigure, 'push');
            app.NextGestureButton.Text = 'Next Gesture';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newDataset

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end